classdef SimuLattice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InitialDesignTab                matlab.ui.container.Tab
        ListBox                         matlab.ui.control.ListBox
        NumericalhomogenizationLabel    matlab.ui.control.Label
        InputfilepathLabel              matlab.ui.control.Label
        ResolutionSliderLabel           matlab.ui.control.Label
        ResolutionSlider                matlab.ui.control.Slider
        RunHomogenizationButton         matlab.ui.control.Button
        ResultPanel                     matlab.ui.container.Panel
        ConstitutiveMatrixLabel         matlab.ui.control.Label
        YoungsShearmodulusandPoissonsratioLabel  matlab.ui.control.Label
        ExEditFieldLabel                matlab.ui.control.Label
        ExEditField                     matlab.ui.control.NumericEditField
        EyEditFieldLabel                matlab.ui.control.Label
        EyEditField                     matlab.ui.control.NumericEditField
        EzEditFieldLabel                matlab.ui.control.Label
        EzEditField                     matlab.ui.control.NumericEditField
        GyzEditFieldLabel               matlab.ui.control.Label
        GyzEditField                    matlab.ui.control.NumericEditField
        GzxEditFieldLabel               matlab.ui.control.Label
        GzxEditField                    matlab.ui.control.NumericEditField
        GxyEditFieldLabel               matlab.ui.control.Label
        GxyEditField                    matlab.ui.control.NumericEditField
        VxyEditFieldLabel               matlab.ui.control.Label
        VxyEditField                    matlab.ui.control.NumericEditField
        VyxEditFieldLabel               matlab.ui.control.Label
        VyxEditField                    matlab.ui.control.NumericEditField
        VxzEditFieldLabel               matlab.ui.control.Label
        VxzEditField                    matlab.ui.control.NumericEditField
        VzxEditFieldLabel               matlab.ui.control.Label
        VzxEditField                    matlab.ui.control.NumericEditField
        VyzEditFieldLabel               matlab.ui.control.Label
        VyzEditField                    matlab.ui.control.NumericEditField
        VzyEditFieldLabel               matlab.ui.control.Label
        VzyEditField                    matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        UITable2                        matlab.ui.control.Table
        Label_2                         matlab.ui.control.Label
        RadiusSliderLabel               matlab.ui.control.Label
        RadiusSlider                    matlab.ui.control.Slider
        GenerateVoxelModelButton        matlab.ui.control.Button
        VoxelizationCompleteLampLabel   matlab.ui.control.Label
        VoxelizationCompleteLamp        matlab.ui.control.Lamp
        YoungsmodulusEditFieldLabel     matlab.ui.control.Label
        YoungsmodulusEditField          matlab.ui.control.NumericEditField
        PoissonsratioEditFieldLabel     matlab.ui.control.Label
        PoissonsratioEditField          matlab.ui.control.NumericEditField
        HomogenizationCompleteLampLabel  matlab.ui.control.Label
        HomogenizationCompleteLamp      matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Switch                          matlab.ui.control.Switch
        EditField                       matlab.ui.control.EditField
        Gauge                           matlab.ui.control.Gauge
        RelativeDensityLabel            matlab.ui.control.Label
        ViewYoungsModulusPlotButton     matlab.ui.control.StateButton
        DesignOptimizationTab           matlab.ui.container.Tab
        JointStiffeningElementLabel     matlab.ui.control.Label
        FindthestiffeningcoefficientsPanel  matlab.ui.container.Panel
        RadiusEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        StrutlengthEditFieldLabel       matlab.ui.control.Label
        StrutlengthEditField            matlab.ui.control.NumericEditField
        Switch_2                        matlab.ui.control.Switch
        Resolution_LSliderLabel         matlab.ui.control.Label
        Resolution_LSlider              matlab.ui.control.Slider
        Resolution_SSliderLabel         matlab.ui.control.Label
        Resolution_SSlider              matlab.ui.control.Slider
        RunButton                       matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        XcoordinateTextAreaLabel        matlab.ui.control.Label
        XcoordinateTextArea             matlab.ui.control.TextArea
        YcoordinateTextAreaLabel        matlab.ui.control.Label
        YcoordinateTextArea             matlab.ui.control.TextArea
        TestcoefficientsPanel           matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        CoefficientvaluePanel           matlab.ui.container.Panel
        TestButton                      matlab.ui.control.Button
        TensileLampLabel                matlab.ui.control.Label
        TensileLamp                     matlab.ui.control.Lamp
        BendingstiffnessEditFieldLabel  matlab.ui.control.Label
        BendingstiffnessEditField       matlab.ui.control.NumericEditField
        BendinglengthEditFieldLabel     matlab.ui.control.Label
        BendinglengthEditField          matlab.ui.control.NumericEditField
        TensilestiffnessEditFieldLabel  matlab.ui.control.Label
        TensilestiffnessEditField       matlab.ui.control.NumericEditField
        TensilelengthEditFieldLabel     matlab.ui.control.Label
        TensilelengthEditField          matlab.ui.control.NumericEditField
        BendingLampLabel                matlab.ui.control.Label
        BendingLamp                     matlab.ui.control.Lamp
        SimulationofthelatticestructurePanel  matlab.ui.container.Panel
        BoundaryConditioinLabel         matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        SupportButton                   matlab.ui.control.Button
        ModelLabel                      matlab.ui.control.Label
        NodelistButton                  matlab.ui.control.Button
        BeamlistButton                  matlab.ui.control.Button
        ReactionForceEditFieldLabel     matlab.ui.control.Label
        ReactionForceEditField          matlab.ui.control.NumericEditField
        RunthemodelButton               matlab.ui.control.Button
        DoneLampLabel                   matlab.ui.control.Label
        DoneLamp                        matlab.ui.control.Lamp
        UITable                         matlab.ui.control.Table
        ELabel                          matlab.ui.control.Label
        EEditField                      matlab.ui.control.NumericEditField
        PoissonsratioEditField_2Label   matlab.ui.control.Label
        PoissonsratioEditField_2        matlab.ui.control.NumericEditField
        DesignValidationTab             matlab.ui.container.Tab
        HybridElementModelLabel         matlab.ui.control.Label
        OpensolidmodelButton            matlab.ui.control.Button
        OpenlatticenodeButton           matlab.ui.control.Button
        Step1Label                      matlab.ui.control.Label
        Step2Label                      matlab.ui.control.Label
        Step3Label                      matlab.ui.control.Label
        OpenlatticestrutButton          matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        ViewlatticeButton               matlab.ui.control.Button
        Step5Label                      matlab.ui.control.Label
        OpenTetrahedralButton           matlab.ui.control.Button
        Step4Label                      matlab.ui.control.Label
        Step6Label_2                    matlab.ui.control.Label
        coefficientforRBE3elementSliderLabel  matlab.ui.control.Label
        coefficientforRBE3elementSlider  matlab.ui.control.Slider
        RadiusEditField_2Label          matlab.ui.control.Label
        RadiusEditField_2               matlab.ui.control.NumericEditField
        Step7Label_2                    matlab.ui.control.Label
        Step8Label_2                    matlab.ui.control.Label
        PreviewRBE3Button               matlab.ui.control.Button
        Step9Label                      matlab.ui.control.Label
        ExportscriptButton              matlab.ui.control.Button
        ClearfigureButton               matlab.ui.control.Button
        PropertyModelTab                matlab.ui.container.Tab
        ManufacturingProcessDropDownLabel  matlab.ui.control.Label
        ManufacturingProcessDropDown    matlab.ui.control.DropDown
        AsbuiltMaterialPropertyLabel    matlab.ui.control.Label
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        StrutthicknessDropDownLabel     matlab.ui.control.Label
        StrutthicknessDropDown          matlab.ui.control.DropDown
        StrutlengthDropDownLabel        matlab.ui.control.Label
        StrutlengthDropDown             matlab.ui.control.DropDown
        PlotButton                      matlab.ui.control.Button
        InputthicknessEditFieldLabel    matlab.ui.control.Label
        InputthicknessEditField         matlab.ui.control.NumericEditField
        InputlengthEditFieldLabel       matlab.ui.control.Label
        InputlengthEditField            matlab.ui.control.NumericEditField
        InputangleEditFieldLabel        matlab.ui.control.Label
        InputangleEditField             matlab.ui.control.NumericEditField
        ResultEditFieldLabel            matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.NumericEditField
        GetvalueButton                  matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        ClearPlotButton                 matlab.ui.control.Button
        SimuLatticeLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        voxel % result of the voxelization code
        homo_matrix % result of the homogenization code
        node_list  % node list of FEA
        beam_list  % beam list of FEA
        load       % load
        support    % support
        node       % node_list for solid lattice hybrid structure
        strut      % strut for solid lattice hybrid structure
        tetra_node % tetrahedral nodes for solid lattice hybrid structure
    end
    

    methods (Access = private)

        % Button pushed function: RunHomogenizationButton
        function RunHomogenizationButtonPushed(app, event)
            young = app.YoungsmodulusEditField.Value;               % Run the homogenization
            poisson = app.PoissonsratioEditField.Value;
            lambda = poisson*young/(1+poisson)/(1-2*poisson);
            mu = young/2/(1+poisson);
            CH = homo3D(1,1,1,lambda,mu,app.voxel);
            app.homo_matrix = CH;
            d=arrayfun(@(x) sprintf('%.2f\n',x),CH,'un',0);         % keep two decimals
            
            app.UITable2.Data = d;
            
            DH = inv(CH);
            
            app.ExEditField.Value = 1/DH(1,1);
            app.EyEditField.Value = 1/DH(2,2);
            app.EzEditField.Value = 1/DH(3,3);
            app.GyzEditField.Value = 1/DH(4,4);
            app.GzxEditField.Value = 1/DH(5,5);
            app.GxyEditField.Value = 1/DH(6,6);
            
            app.VxyEditField.Value = -DH(2,1)/DH(1,1);
            app.VyxEditField.Value = -DH(1,2)/DH(2,2);
            app.VxzEditField.Value = -DH(3,1)/DH(1,1);
            app.VzxEditField.Value = -DH(1,3)/DH(3,3);
            app.VyzEditField.Value = -DH(3,2)/DH(2,2);
            app.VzyEditField.Value = -DH(2,3)/DH(3,3);
            
            app.HomogenizationCompleteLamp.Color = 'green';


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;                      % show the image of topologies
            img_path = strcat('img\',value,'.JPG');
            RGB = imread(img_path);
            ax = uiaxes(app.InitialDesignTab,'Position',[200 330 170 150]);
            image(RGB,'Parent',ax);
            set(ax,'visible','off');
            app.VoxelizationCompleteLamp.Color = [0.8 0.8 0.8];
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;                          % switch customer or existing
            switch value
                case 'Existing'
                    app.Lamp.Color = 'green';
                    app.Lamp_2.Color = [0.8 0.8 0.8];
                case 'Customize'
                    app.Lamp_2.Color = 'green';
                    app.Lamp.Color = [0.8 0.8 0.8];
            end
            app.VoxelizationCompleteLamp.Color = [0.8 0.8 0.8];
        end

        % Value changing function: ResolutionSlider
        function ResolutionSliderValueChanging(app, event)
            changingValue = event.Value;                        % select the resolutioin of the homogenization
            app.ResolutionSliderLabel.Text = strcat('Resolution =  ',int2str(round(changingValue)));
            app.VoxelizationCompleteLamp.Color = [0.8 0.8 0.8];
        end

        % Value changing function: RadiusSlider
        function RadiusSliderValueChanging(app, event)
            changingValue = event.Value;                         % select the radius
            app.RadiusSliderLabel.Text = strcat('Radius =  ',sprintf('%.3f',changingValue));
            app.VoxelizationCompleteLamp.Color = [0.8 0.8 0.8];
        end

        % Button pushed function: GenerateVoxelModelButton
        function GenerateVoxelModelButtonPushed(app, event)
            app.VoxelizationCompleteLamp.Color = 'green';       % run the voxelization code
            value = app.Switch.Value;
            switch value
                case 'Existing'
                    file_path = strcat('topology\', app.ListBox.Value, '.txt');
                case 'Customize'
                    file_path = app.EditField;
            end
            radius = app.RadiusSlider.Value;
            n = round(app.ResolutionSlider.Value);
            [app.voxel, density] = GenerateVoxel(n,file_path,radius);
            app.RelativeDensityLabel.Text = strcat('Relative Density =',sprintf('%.2f%%',density*100));
            app.Gauge.Value = density*100;
        end

        % Value changed function: ViewYoungsModulusPlotButton
        function ViewYoungsModulusPlotButtonValueChanged(app, event)
            % show the young's modulus plot
            [x,y,z,c] = visual(app.homo_matrix);
            s = surf(app.UIAxes3,x,y,z,c);
            s.EdgeColor = 'none';
            s.FaceColor = 'interp';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.Switch_2.Value;
            
            
            radius = app.RadiusEditField.Value;
            length = app.StrutlengthEditField.Value;
            solid_x = str2double(app.XcoordinateTextArea.Value);
            solid_y = str2double(app.YcoordinateTextArea.Value);
            resolution_s = app.Resolution_SSlider.Value;
            resolution_l = app.Resolution_LSlider.Value;
            
            switch value
                case 'Bending'
                    [l,b,e,index_l,index_s] = test_bending(resolution_l,resolution_s,solid_x,solid_y,length,radius);
                    app.BendingstiffnessEditField.Value = index_s;
                    app.BendinglengthEditField.Value = index_l;
                    
                case 'Tensile'
                    [l,b,e,index_l,index_s] = test_tensile(resolution_l,resolution_s,solid_x,solid_y,length,radius);
                    app.TensilestiffnessEditField.Value = index_s;
                    app.TensilelengthEditField.Value = index_l;
            end
            
            s = surf(app.UIAxes, b,l,e);
            s.EdgeColor = 'none';
            
            app.Lamp_3.Color = 'green';
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];
            value = app.Switch_2.Value;
            switch value
                case 'Bending'
                    app.BendingLamp.Color = 'green';
                    app.TensileLamp.Color = [0.8 0.8 0.8];
                case 'Tensile'
                    app.BendingLamp.Color = [0.8 0.8 0.8];
                    app.TensileLamp.Color = 'green';
            end
        end

        % Value changed function: StrutlengthEditField
        function StrutlengthEditFieldValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];            
        end

        % Value changed function: XcoordinateTextArea
        function XcoordinateTextAreaValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];
        end

        % Value changed function: YcoordinateTextArea
        function YcoordinateTextAreaValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];            
        end

        % Value changed function: Resolution_SSlider
        function Resolution_SSliderValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];               
        end

        % Value changed function: Resolution_LSlider
        function Resolution_LSliderValueChanged(app, event)
            app.Lamp_3.Color = [0.8 0.8 0.8];            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            value = app.Switch_2.Value;
            radius = app.RadiusEditField.Value;
            length = app.StrutlengthEditField.Value;  
            solid_x = str2double(app.XcoordinateTextArea.Value);
            solid_y = str2double(app.YcoordinateTextArea.Value);
            switch value
                case 'Bending'
                    c_s = app.BendingstiffnessEditField.Value;
                    c_l = app.BendinglengthEditField.Value;
                    [x_co,u_in,u_solid] = find_bending(c_l,c_s,solid_x,solid_y,length,radius);
                case 'Tensile'
                    c_s = app.TensilestiffnessEditField.Value;
                    c_l = app.TensilelengthEditField.Value;
                    [x_co,u_in,u_solid] = find_tensile(c_l,c_s,solid_x,solid_y,length,radius);
            end            
            
            s = plot(app.UIAxes2, x_co,u_in,x_co,u_solid);
            s(1).LineWidth = 2;
            s(1).Marker = '.';
            s(1).MarkerSize = 20;
            s(2).LineWidth = 2;
        end

        % Button pushed function: RunthemodelButton
        function RunthemodelButtonPushed(app, event)
            [u, force] = stiffening_element(app.beam_list, app.node_list, app.RadiusEditField.Value, app.EEditField.Value, ...
                   app.PoissonsratioEditField.Value, app.BendingstiffnessEditField.Value, app.BendinglengthEditField.Value, ...
                   app.TensilestiffnessEditField.Value, app.TensilelengthEditField.Value, app.load, app.support);
            displacement = reshape(u,6,[]);
            displacement = displacement';
            data = num2cell(displacement);
            app.UITable.Data = data;
            app.ReactionForceEditField.Value = force;
        end

        % Button pushed function: NodelistButton
        function NodelistButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%f %f %f';
                sizeA = [3 Inf];
                app.node_list = fscanf(fileID,formatSpec,sizeA);
                app.node_list = app.node_list';
            end
        end

        % Button pushed function: BeamlistButton
        function BeamlistButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%d %d';
                sizeA = [2 Inf];
                app.beam_list = fscanf(fileID,formatSpec,sizeA);
                app.beam_list = app.beam_list';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%d';
                app.load = fscanf(fileID,formatSpec);
                app.load = app.load';
            end
        end

        % Button pushed function: SupportButton
        function SupportButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%d';
                bottom = fscanf(fileID,formatSpec);
                bottom = bottom';
                app.support = [bottom*6-5 bottom*6-4 bottom*6-3 bottom*6-2 bottom*6-1 bottom*6 ...
                    app.load*6-5 app.load*6-4 app.load*6-3 app.load*6-2 app.load*6-1 app.load*6];
            end
        end

        % Button pushed function: OpensolidmodelButton
        function OpensolidmodelButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.stl');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fv = stlread(strcat(path,file));
                patch(app.UIAxes4,fv,'FaceColor',       [0.8 0.8 1.0], ...
                    'EdgeColor',       'none',        ...
                    'FaceLighting',    'gouraud',     ...
                    'AmbientStrength', 0.15);
                camlight(app.UIAxes4,'headlight');
                material(app.UIAxes4,'dull');
                axis(app.UIAxes4,'image');
                view(app.UIAxes4,[-135 35]);
            end
        end

        % Button pushed function: OpenlatticenodeButton
        function OpenlatticenodeButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%f %f %f';
                sizeA = [3 Inf];
                app.node = fscanf(fileID,formatSpec,sizeA);
                app.node = app.node';
            end
        end

        % Button pushed function: OpenlatticestrutButton
        function OpenlatticestrutButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%d %d';
                sizeA = [2 Inf];
                app.strut = fscanf(fileID,formatSpec,sizeA);
                app.strut = app.strut';
            end
        end

        % Button pushed function: ViewlatticeButton
        function ViewlatticeButtonPushed(app, event)
            lines = app.strut;
            points = app.node;
            n = size(lines,1);
            hold(app.UIAxes4);
            for i = 1:n
                s_node = points(lines(i,1),:);
                e_node = points(lines(i,2),:);
                plot3(app.UIAxes4,[s_node(1), e_node(1)],[s_node(2), e_node(2)],[s_node(3), e_node(3)],'r');
            end
            hold(app.UIAxes4);
        end

        % Button pushed function: OpenTetrahedralButton
        function OpenTetrahedralButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.txt');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                fileID = fopen(strcat(path,file),'r');
                formatSpec = '%f %f %f';
                sizeA = [3 Inf];
                app.tetra_node = fscanf(fileID,formatSpec,sizeA);
                app.tetra_node = app.tetra_node';
            end
        end

        % Button pushed function: ClearfigureButton
        function ClearfigureButtonPushed(app, event)
            app.UIAxes4.cla;
        end

        % Button pushed function: PreviewRBE3Button
        function PreviewRBE3ButtonPushed(app, event)
            n = size(app.node,1);
            m = size(app.tetra_node,1);
            threshold = app.RadiusEditField_2.Value*2*app.coefficientforRBE3elementSlider.Value;
            rbe3 = zeros(1,1);
            count = zeros(1,n);
            total_count = 0;
            for i = 1:n
                for j = 1:m
                    if norm(app.node(i,:)-app.tetra_node(j,:)) < threshold
                        count(1,i) = count(1,i) + 1;
                        total_count = total_count + 1;
                        rbe3(total_count) = j;
                    end
                end
            end
            x = app.tetra_node(rbe3,1);
            y = app.tetra_node(rbe3,2);
            z = app.tetra_node(rbe3,3);
            hold(app.UIAxes4);
            scatter3(app.UIAxes4,x,y,z,'b');
            hold(app.UIAxes4);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            M = dlmread('material\horizontal.csv');
            thickness  = str2double(app.StrutthicknessDropDown.Value);
            length = str2double(app.StrutlengthDropDown.Value);
            modulus = zeros(1,7);
            count = 0;
            for i = 1:size(M,1)
                if M(i,1) == length
                    if M(i,2) == thickness
                        count = count + 1;
                        modulus(count) = M(i,4);
                    end
                end
            end
            hold(app.UIAxes5);
            plot(app.UIAxes5, [0,15,30,45,60,75,90],modulus);
            hold(app.UIAxes5);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            app.UIAxes5.cla;
        end

        % Button pushed function: GetvalueButton
        function GetvalueButtonPushed(app, event)
            angle = app.InputangleEditField.Value;
            length = app.InputlengthEditField.Value;
            thickness = app.InputthicknessEditField.Value;
            address_horizontal = 'material\horizontal.csv';
            address_other = 'material\new_input.csv';
            if angle == 0
                modulus = interp_horizontal(length,thickness,address_horizontal);
            else
                modulus = interp(length,thickness,angle,address_other);
            end
            app.ResultEditField.Value = modulus;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitialDesignTab = uitab(app.TabGroup);
            app.InitialDesignTab.Title = 'Initial Design';
            app.ListBox = uilistbox(app.InitialDesignTab);
            app.ListBox.Items = {'Grid', 'X_shape', 'Cubic_center', 'Face_center', 'Octet', 'Tesseract', 'Vintiles'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Grid';
            app.NumericalhomogenizationLabel = uilabel(app.InitialDesignTab);
            app.NumericalhomogenizationLabel.Text = 'Numerical homogenization';
            app.InputfilepathLabel = uilabel(app.InitialDesignTab);
            app.InputfilepathLabel.Text = 'Input file path';
            app.ResolutionSliderLabel = uilabel(app.InitialDesignTab);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.InitialDesignTab);
            app.ResolutionSlider.ValueChangingFcn = createCallbackFcn(app, @ResolutionSliderValueChanging, true);
            app.RunHomogenizationButton = uibutton(app.InitialDesignTab, 'push');
            app.RunHomogenizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunHomogenizationButtonPushed, true);
            app.RunHomogenizationButton.Text = 'Run Homogenization';
            app.ResultPanel = uipanel(app.InitialDesignTab);
            app.ResultPanel.Title = 'Result';
            app.ConstitutiveMatrixLabel = uilabel(app.ResultPanel);
            app.ConstitutiveMatrixLabel.Text = 'Constitutive Matrix';
            app.YoungsShearmodulusandPoissonsratioLabel = uilabel(app.ResultPanel);
            app.YoungsShearmodulusandPoissonsratioLabel.Text = 'Young''s, Shear modulus, and Poisson''s ratio';
            app.ExEditFieldLabel = uilabel(app.ResultPanel);
            app.ExEditFieldLabel.Text = 'Ex';
            app.ExEditField = uieditfield(app.ResultPanel, 'numeric');
            app.EyEditFieldLabel = uilabel(app.ResultPanel);
            app.EyEditFieldLabel.Text = 'Ey';
            app.EyEditField = uieditfield(app.ResultPanel, 'numeric');
            app.EzEditFieldLabel = uilabel(app.ResultPanel);
            app.EzEditFieldLabel.Text = 'Ez';
            app.EzEditField = uieditfield(app.ResultPanel, 'numeric');
            app.GyzEditFieldLabel = uilabel(app.ResultPanel);
            app.GyzEditFieldLabel.Text = 'Gyz';
            app.GyzEditField = uieditfield(app.ResultPanel, 'numeric');
            app.GzxEditFieldLabel = uilabel(app.ResultPanel);
            app.GzxEditFieldLabel.Text = 'Gzx';
            app.GzxEditField = uieditfield(app.ResultPanel, 'numeric');
            app.GxyEditFieldLabel = uilabel(app.ResultPanel);
            app.GxyEditFieldLabel.Text = 'Gxy';
            app.GxyEditField = uieditfield(app.ResultPanel, 'numeric');
            app.VxyEditFieldLabel = uilabel(app.ResultPanel);
            app.VxyEditFieldLabel.Text = 'Vxy';
            app.VxyEditField = uieditfield(app.ResultPanel, 'numeric');
            app.VyxEditFieldLabel = uilabel(app.ResultPanel);
            app.VyxEditFieldLabel.Text = 'Vyx';
            app.VyxEditField = uieditfield(app.ResultPanel, 'numeric');
            app.VxzEditFieldLabel = uilabel(app.ResultPanel);
            app.VxzEditFieldLabel.Text = 'Vxz';
            app.VxzEditField = uieditfield(app.ResultPanel, 'numeric');
            app.VzxEditFieldLabel = uilabel(app.ResultPanel);
            app.VzxEditFieldLabel.Text = 'Vzx';
            app.VzxEditField = uieditfield(app.ResultPanel, 'numeric');
            app.VyzEditFieldLabel = uilabel(app.ResultPanel);
            app.VyzEditFieldLabel.Text = 'Vyz';
            app.VyzEditField = uieditfield(app.ResultPanel, 'numeric');
            app.VzyEditFieldLabel = uilabel(app.ResultPanel);
            app.VzyEditFieldLabel.Text = 'Vzy';
            app.VzyEditField = uieditfield(app.ResultPanel, 'numeric');
            app.UIAxes3 = uiaxes(app.ResultPanel);
            app.UITable2 = uitable(app.ResultPanel);
            app.UITable2.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable2.RowName = {};
            app.Label_2 = uilabel(app.InitialDesignTab);
            app.Label_2.Text = '';
            app.RadiusSliderLabel = uilabel(app.InitialDesignTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.InitialDesignTab);
            app.RadiusSlider.Limits = [0 0.5];
            app.RadiusSlider.ValueChangingFcn = createCallbackFcn(app, @RadiusSliderValueChanging, true);
            app.GenerateVoxelModelButton = uibutton(app.InitialDesignTab, 'push');
            app.GenerateVoxelModelButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateVoxelModelButtonPushed, true);
            app.GenerateVoxelModelButton.Text = 'Generate Voxel Model';
            app.VoxelizationCompleteLampLabel = uilabel(app.InitialDesignTab);
            app.VoxelizationCompleteLampLabel.Text = 'Voxelization Complete';
            app.VoxelizationCompleteLamp = uilamp(app.InitialDesignTab);
            app.YoungsmodulusEditFieldLabel = uilabel(app.InitialDesignTab);
            app.YoungsmodulusEditFieldLabel.Text = 'Young''s modulus';
            app.YoungsmodulusEditField = uieditfield(app.InitialDesignTab, 'numeric');
            app.PoissonsratioEditFieldLabel = uilabel(app.InitialDesignTab);
            app.PoissonsratioEditFieldLabel.Text = 'Poisson''s ratio';
            app.PoissonsratioEditField = uieditfield(app.InitialDesignTab, 'numeric');
            app.HomogenizationCompleteLampLabel = uilabel(app.InitialDesignTab);
            app.HomogenizationCompleteLampLabel.Text = 'Homogenization Complete';
            app.HomogenizationCompleteLamp = uilamp(app.InitialDesignTab);
            app.Lamp = uilamp(app.InitialDesignTab);
            app.Lamp_2 = uilamp(app.InitialDesignTab);
            app.Switch = uiswitch(app.InitialDesignTab, 'slider');
            app.Switch.Items = {'Existing', 'Customize'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Existing';
            app.EditField = uieditfield(app.InitialDesignTab, 'text');
            app.Gauge = uigauge(app.InitialDesignTab, 'circular');
            app.Gauge.Limits = [0 50];
            app.RelativeDensityLabel = uilabel(app.InitialDesignTab);
            app.RelativeDensityLabel.Text = 'Relative Density';
            app.ViewYoungsModulusPlotButton = uibutton(app.InitialDesignTab, 'state');
            app.ViewYoungsModulusPlotButton.ValueChangedFcn = createCallbackFcn(app, @ViewYoungsModulusPlotButtonValueChanged, true);
            app.ViewYoungsModulusPlotButton.Text = 'View Young''s Modulus Plot';
            app.DesignOptimizationTab = uitab(app.TabGroup);
            app.DesignOptimizationTab.Title = 'Design Optimization';
            app.JointStiffeningElementLabel = uilabel(app.DesignOptimizationTab);
            app.JointStiffeningElementLabel.Text = 'Joint Stiffening Element';
            app.FindthestiffeningcoefficientsPanel = uipanel(app.DesignOptimizationTab);
            app.FindthestiffeningcoefficientsPanel.Title = 'Find the stiffening coefficients';
            app.RadiusEditFieldLabel = uilabel(app.FindthestiffeningcoefficientsPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.FindthestiffeningcoefficientsPanel, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.FindthestiffeningcoefficientsPanel);
            app.StrutlengthEditFieldLabel = uilabel(app.FindthestiffeningcoefficientsPanel);
            app.StrutlengthEditFieldLabel.Text = 'Strut length';
            app.StrutlengthEditField = uieditfield(app.FindthestiffeningcoefficientsPanel, 'numeric');
            app.StrutlengthEditField.ValueChangedFcn = createCallbackFcn(app, @StrutlengthEditFieldValueChanged, true);
            app.Switch_2 = uiswitch(app.FindthestiffeningcoefficientsPanel, 'slider');
            app.Switch_2.Items = {'Bending', 'Tensile'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Bending';
            app.Resolution_LSliderLabel = uilabel(app.FindthestiffeningcoefficientsPanel);
            app.Resolution_LSliderLabel.Text = 'Resolution_L';
            app.Resolution_LSlider = uislider(app.FindthestiffeningcoefficientsPanel);
            app.Resolution_LSlider.Limits = [0 0.5];
            app.Resolution_LSlider.ValueChangedFcn = createCallbackFcn(app, @Resolution_LSliderValueChanged, true);
            app.Resolution_SSliderLabel = uilabel(app.FindthestiffeningcoefficientsPanel);
            app.Resolution_SSliderLabel.Text = 'Resolution_S';
            app.Resolution_SSlider = uislider(app.FindthestiffeningcoefficientsPanel);
            app.Resolution_SSlider.Limits = [0 1];
            app.Resolution_SSlider.ValueChangedFcn = createCallbackFcn(app, @Resolution_SSliderValueChanged, true);
            app.RunButton = uibutton(app.FindthestiffeningcoefficientsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Lamp_3 = uilamp(app.FindthestiffeningcoefficientsPanel);
            app.XcoordinateTextAreaLabel = uilabel(app.FindthestiffeningcoefficientsPanel);
            app.XcoordinateTextAreaLabel.Text = 'X coordinate';
            app.XcoordinateTextArea = uitextarea(app.FindthestiffeningcoefficientsPanel);
            app.XcoordinateTextArea.ValueChangedFcn = createCallbackFcn(app, @XcoordinateTextAreaValueChanged, true);
            app.YcoordinateTextAreaLabel = uilabel(app.FindthestiffeningcoefficientsPanel);
            app.YcoordinateTextAreaLabel.Text = 'Y coordinate';
            app.YcoordinateTextArea = uitextarea(app.FindthestiffeningcoefficientsPanel);
            app.YcoordinateTextArea.ValueChangedFcn = createCallbackFcn(app, @YcoordinateTextAreaValueChanged, true);
            app.TestcoefficientsPanel = uipanel(app.DesignOptimizationTab);
            app.TestcoefficientsPanel.Title = 'Test coefficients';
            app.UIAxes2 = uiaxes(app.TestcoefficientsPanel);
            app.CoefficientvaluePanel = uipanel(app.DesignOptimizationTab);
            app.CoefficientvaluePanel.Title = 'Coefficient value';
            app.TestButton = uibutton(app.CoefficientvaluePanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TensileLampLabel = uilabel(app.CoefficientvaluePanel);
            app.TensileLampLabel.Text = 'Tensile';
            app.TensileLamp = uilamp(app.CoefficientvaluePanel);
            app.BendingstiffnessEditFieldLabel = uilabel(app.CoefficientvaluePanel);
            app.BendingstiffnessEditFieldLabel.Text = 'Bending stiffness';
            app.BendingstiffnessEditField = uieditfield(app.CoefficientvaluePanel, 'numeric');
            app.BendinglengthEditFieldLabel = uilabel(app.CoefficientvaluePanel);
            app.BendinglengthEditFieldLabel.Text = 'Bending length';
            app.BendinglengthEditField = uieditfield(app.CoefficientvaluePanel, 'numeric');
            app.TensilestiffnessEditFieldLabel = uilabel(app.CoefficientvaluePanel);
            app.TensilestiffnessEditFieldLabel.Text = 'Tensile stiffness';
            app.TensilestiffnessEditField = uieditfield(app.CoefficientvaluePanel, 'numeric');
            app.TensilelengthEditFieldLabel = uilabel(app.CoefficientvaluePanel);
            app.TensilelengthEditFieldLabel.Text = 'Tensile length';
            app.TensilelengthEditField = uieditfield(app.CoefficientvaluePanel, 'numeric');
            app.BendingLampLabel = uilabel(app.CoefficientvaluePanel);
            app.BendingLampLabel.Text = 'Bending';
            app.BendingLamp = uilamp(app.CoefficientvaluePanel);
            app.SimulationofthelatticestructurePanel = uipanel(app.DesignOptimizationTab);
            app.SimulationofthelatticestructurePanel.Title = 'Simulation of the lattice structure';
            app.BoundaryConditioinLabel = uilabel(app.SimulationofthelatticestructurePanel);
            app.BoundaryConditioinLabel.Text = 'Boundary Conditioin';
            app.LoadButton = uibutton(app.SimulationofthelatticestructurePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SupportButton = uibutton(app.SimulationofthelatticestructurePanel, 'push');
            app.SupportButton.ButtonPushedFcn = createCallbackFcn(app, @SupportButtonPushed, true);
            app.SupportButton.Text = 'Support';
            app.ModelLabel = uilabel(app.SimulationofthelatticestructurePanel);
            app.ModelLabel.Text = 'Model';
            app.NodelistButton = uibutton(app.SimulationofthelatticestructurePanel, 'push');
            app.NodelistButton.ButtonPushedFcn = createCallbackFcn(app, @NodelistButtonPushed, true);
            app.NodelistButton.Text = 'Node list';
            app.BeamlistButton = uibutton(app.SimulationofthelatticestructurePanel, 'push');
            app.BeamlistButton.ButtonPushedFcn = createCallbackFcn(app, @BeamlistButtonPushed, true);
            app.BeamlistButton.Text = 'Beam list';
            app.ReactionForceEditFieldLabel = uilabel(app.SimulationofthelatticestructurePanel);
            app.ReactionForceEditFieldLabel.Text = 'Reaction Force';
            app.ReactionForceEditField = uieditfield(app.SimulationofthelatticestructurePanel, 'numeric');
            app.RunthemodelButton = uibutton(app.SimulationofthelatticestructurePanel, 'push');
            app.RunthemodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunthemodelButtonPushed, true);
            app.RunthemodelButton.Text = 'Run the model';
            app.DoneLampLabel = uilabel(app.SimulationofthelatticestructurePanel);
            app.DoneLampLabel.Text = 'Done';
            app.DoneLamp = uilamp(app.SimulationofthelatticestructurePanel);
            app.UITable = uitable(app.SimulationofthelatticestructurePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.UITable.RowName = {};
            app.ELabel = uilabel(app.SimulationofthelatticestructurePanel);
            app.ELabel.Text = 'E';
            app.EEditField = uieditfield(app.SimulationofthelatticestructurePanel, 'numeric');
            app.EEditField.Value = 1;
            app.PoissonsratioEditField_2Label = uilabel(app.SimulationofthelatticestructurePanel);
            app.PoissonsratioEditField_2Label.Text = 'Poisson''s ratio';
            app.PoissonsratioEditField_2 = uieditfield(app.SimulationofthelatticestructurePanel, 'numeric');
            app.PoissonsratioEditField_2.Value = 0.3;
            app.DesignValidationTab = uitab(app.TabGroup);
            app.DesignValidationTab.Title = 'Design Validation';
            app.HybridElementModelLabel = uilabel(app.DesignValidationTab);
            app.HybridElementModelLabel.Text = 'Hybrid Element Model';
            app.OpensolidmodelButton = uibutton(app.DesignValidationTab, 'push');
            app.OpensolidmodelButton.ButtonPushedFcn = createCallbackFcn(app, @OpensolidmodelButtonPushed, true);
            app.OpensolidmodelButton.Text = 'Open solid model';
            app.OpenlatticenodeButton = uibutton(app.DesignValidationTab, 'push');
            app.OpenlatticenodeButton.ButtonPushedFcn = createCallbackFcn(app, @OpenlatticenodeButtonPushed, true);
            app.OpenlatticenodeButton.Text = 'Open lattice node';
            app.Step1Label = uilabel(app.DesignValidationTab);
            app.Step1Label.Text = 'Step 1:';
            app.Step2Label = uilabel(app.DesignValidationTab);
            app.Step2Label.Text = 'Step 2:';
            app.Step3Label = uilabel(app.DesignValidationTab);
            app.Step3Label.Text = 'Step 3:';
            app.OpenlatticestrutButton = uibutton(app.DesignValidationTab, 'push');
            app.OpenlatticestrutButton.ButtonPushedFcn = createCallbackFcn(app, @OpenlatticestrutButtonPushed, true);
            app.OpenlatticestrutButton.Text = 'Open lattice strut';
            app.UIAxes4 = uiaxes(app.DesignValidationTab);
            title(app.UIAxes4, 'Preview')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ViewlatticeButton = uibutton(app.DesignValidationTab, 'push');
            app.ViewlatticeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewlatticeButtonPushed, true);
            app.ViewlatticeButton.Text = 'View lattice';
            app.Step5Label = uilabel(app.DesignValidationTab);
            app.Step5Label.Text = 'Step 5:';
            app.OpenTetrahedralButton = uibutton(app.DesignValidationTab, 'push');
            app.OpenTetrahedralButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTetrahedralButtonPushed, true);
            app.OpenTetrahedralButton.Text = 'Open Tetrahedral';
            app.Step4Label = uilabel(app.DesignValidationTab);
            app.Step4Label.Text = 'Step 4:';
            app.Step6Label_2 = uilabel(app.DesignValidationTab);
            app.Step6Label_2.Text = 'Step 6:';
            app.coefficientforRBE3elementSliderLabel = uilabel(app.DesignValidationTab);
            app.coefficientforRBE3elementSliderLabel.Text = 'coefficient for RBE3 element';
            app.coefficientforRBE3elementSlider = uislider(app.DesignValidationTab);
            app.coefficientforRBE3elementSlider.Limits = [0 2];
            app.RadiusEditField_2Label = uilabel(app.DesignValidationTab);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.RadiusEditField_2 = uieditfield(app.DesignValidationTab, 'numeric');
            app.Step7Label_2 = uilabel(app.DesignValidationTab);
            app.Step7Label_2.Text = 'Step 7:';
            app.Step8Label_2 = uilabel(app.DesignValidationTab);
            app.Step8Label_2.Text = 'Step 8:';
            app.PreviewRBE3Button = uibutton(app.DesignValidationTab, 'push');
            app.PreviewRBE3Button.ButtonPushedFcn = createCallbackFcn(app, @PreviewRBE3ButtonPushed, true);
            app.PreviewRBE3Button.Text = 'Preview RBE3';
            app.Step9Label = uilabel(app.DesignValidationTab);
            app.Step9Label.Text = 'Step 9:';
            app.ExportscriptButton = uibutton(app.DesignValidationTab, 'push');
            app.ExportscriptButton.Text = 'Export script';
            app.ClearfigureButton = uibutton(app.DesignValidationTab, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfigureButtonPushed, true);
            app.ClearfigureButton.Text = 'Clear figure';
            app.PropertyModelTab = uitab(app.TabGroup);
            app.PropertyModelTab.Title = 'Property Model';
            app.ManufacturingProcessDropDownLabel = uilabel(app.PropertyModelTab);
            app.ManufacturingProcessDropDownLabel.Text = 'Manufacturing Process';
            app.ManufacturingProcessDropDown = uidropdown(app.PropertyModelTab);
            app.ManufacturingProcessDropDown.Items = {'Select', 'FDM'};
            app.ManufacturingProcessDropDown.ItemsData = {'[0', '1]'};
            app.ManufacturingProcessDropDown.Value = '[0';
            app.AsbuiltMaterialPropertyLabel = uilabel(app.PropertyModelTab);
            app.AsbuiltMaterialPropertyLabel.Text = 'As-built Material Property';
            app.MaterialDropDownLabel = uilabel(app.PropertyModelTab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.PropertyModelTab);
            app.MaterialDropDown.Items = {'Select', 'PLA'};
            app.MaterialDropDown.ItemsData = {'[0', '1]'};
            app.MaterialDropDown.Value = '[0';
            app.StrutthicknessDropDownLabel = uilabel(app.PropertyModelTab);
            app.StrutthicknessDropDownLabel.Text = 'Strut thickness';
            app.StrutthicknessDropDown = uidropdown(app.PropertyModelTab);
            app.StrutthicknessDropDown.Items = {'Select', '1', '2', '3'};
            app.StrutthicknessDropDown.ItemsData = {'0', '1', '2', '3'};
            app.StrutthicknessDropDown.Value = '0';
            app.StrutlengthDropDownLabel = uilabel(app.PropertyModelTab);
            app.StrutlengthDropDownLabel.Text = 'Strut length';
            app.StrutlengthDropDown = uidropdown(app.PropertyModelTab);
            app.StrutlengthDropDown.Items = {'Select', '5', '10', '20', '30'};
            app.StrutlengthDropDown.ItemsData = {'0', '5', '10', '20', '30'};
            app.StrutlengthDropDown.Value = '0';
            app.PlotButton = uibutton(app.PropertyModelTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InputthicknessEditFieldLabel = uilabel(app.PropertyModelTab);
            app.InputthicknessEditFieldLabel.Text = 'Input thickness';
            app.InputthicknessEditField = uieditfield(app.PropertyModelTab, 'numeric');
            app.InputlengthEditFieldLabel = uilabel(app.PropertyModelTab);
            app.InputlengthEditFieldLabel.Text = 'Input length';
            app.InputlengthEditField = uieditfield(app.PropertyModelTab, 'numeric');
            app.InputangleEditFieldLabel = uilabel(app.PropertyModelTab);
            app.InputangleEditFieldLabel.Text = 'Input angle';
            app.InputangleEditField = uieditfield(app.PropertyModelTab, 'numeric');
            app.ResultEditFieldLabel = uilabel(app.PropertyModelTab);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.PropertyModelTab, 'numeric');
            app.GetvalueButton = uibutton(app.PropertyModelTab, 'push');
            app.GetvalueButton.ButtonPushedFcn = createCallbackFcn(app, @GetvalueButtonPushed, true);
            app.GetvalueButton.Text = 'Get value';
            app.UIAxes5 = uiaxes(app.PropertyModelTab);
            title(app.UIAxes5, 'Material Property')
            xlabel(app.UIAxes5, 'Angle')
            ylabel(app.UIAxes5, 'Elastic Modulus (MPa)')
            app.ClearPlotButton = uibutton(app.PropertyModelTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.SimuLatticeLabel = uilabel(app.UIFigure);
            app.SimuLatticeLabel.Text = 'SimuLattice';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'A simulation platform to support the design of lattice structures fabricated by additive manufactuiring';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimuLattice

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end